classdef termo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        IRRefImageUI                   matlab.ui.control.Image
        ChoosereferenceIRimageButton   matlab.ui.control.Button
        VISRefImageUI                  matlab.ui.control.Image
        ChoosereferenceVISimageButton  matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ResultLabel                    matlab.ui.control.Label
        RegisterImageButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        IRRefPoints, VISRefPoints, RegistrationTransform % Description
        VISRefImage, IRRefImage % Description
    end
    
    methods (Access = private)
        
        function XY = selectMarkerPoints(app, img)
            f = figure;

            imagesc(img);

            pointsNumber = 4;
            title("Select 4 marker points");
            X = [];
            Y = [];
            
            for i=1:pointsNumber
                [x, y] = ginput(1);
                
                hold on
                plot(x(1), y(1), 'r*');
                text(x, y, ['  ', num2str(i)], 'Color', 'r');
                drawnow
                X = [X x];
                Y = [Y y];
            end
            close(f);
            
            [X, XIdx] = sort(X);
            Y = Y(XIdx);
            
            XY = [X; Y];
        end
        
        function validated = validateIRVISSelectedPoints(app)
            POINTS_NO = 4;
            validated = (length(app.VISRefPoints) == POINTS_NO) && (length(app.IRRefPoints) == POINTS_NO);
        end
        
        function transform = registerImageIfValid(app)
             if(app.validateIRVISSelectedPoints())
                fixed = app.IRRefPoints;
                moving = app.VISRefPoints;
                
                [d,Z,transform] = procrustes(fixed,moving);
            end
        end
    end
    
    methods (Access = public)
        
        function RegisterImages(app)
            fixed = app.IRRefImage; % fixed = root image
            moving = app.VISRefImage;
            [movingPoints, fixedPoints] = cpselect(moving, fixed,'Wait',true);
%             points = load('points.mat');
%             movingPoints = points.movingPoints;
%             fixedPoints = points.fixedPoints;
            
            tform = fitgeotrans(movingPoints, fixedPoints, 'projective');
            movingRegistered = imwarp(moving, tform, 'OutputView', imref2d(size(fixed)));
            
            figure;
            imshowpair(fixed,movingRegistered);
            figure;
            imshow(movingRegistered);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosereferenceIRimageButton
        function ChoosereferenceIRimageButtonPushed(app, event)
            [file,path] = uigetfile('*.png', 'Select a Reference IR Image File');
            app.IRRefImageUI.ImageSource = fullfile(path,file);
            app.IRRefImage = imread(fullfile(path,file));
            
%             app.IRRefPoints = app.selectMarkerPoints(img);
%             app.RegistrationTransform = app.registerImageIfValid();

        end

        % Button pushed function: ChoosereferenceVISimageButton
        function ChoosereferenceVISimageButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg', 'Select a Reference VIS Image File');
            app.VISRefImageUI.ImageSource = fullfile(path,file);
            app.VISRefImage = imread(fullfile(path,file));
            
%             app.VISRefPoints = app.selectMarkerPoints(img);
%             app.RegistrationTransform = app.registerImageIfValid();
        end

        % Button pushed function: RegisterImageButton
        function RegisterImageButtonPushed(app, event)
            app.RegisterImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.IRRefImageUI = uiimage(app.GridLayout);
            app.ChoosereferenceIRimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceIRimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceIRimageButtonPushed, true);
            app.ChoosereferenceIRimageButton.Text = 'Choose reference IR image';
            app.VISRefImageUI = uiimage(app.GridLayout);
            app.ChoosereferenceVISimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceVISimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceVISimageButtonPushed, true);
            app.ChoosereferenceVISimageButton.Text = 'Choose reference VIS image';
            app.Image2 = uiimage(app.GridLayout);
            app.ResultLabel = uilabel(app.GridLayout);
            app.ResultLabel.Text = 'Result';
            app.RegisterImageButton = uibutton(app.GridLayout, 'push');
            app.RegisterImageButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImageButtonPushed, true);
            app.RegisterImageButton.Text = 'Register Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = termo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end